classdef main_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        Label               matlab.ui.control.Label
        InputImage          matlab.ui.control.Image
        GrayHistgram        matlab.ui.control.Image
        ShowGrayHistgram    matlab.ui.control.Button
        GrayHistgramUIAxes  matlab.ui.control.UIAxes
        Tab2                matlab.ui.container.Tab
    end


    methods (Access = public)

        % 统计图像灰度直方图
        function histogram = gray_histogram(app, image)
            image = rgb2gray(image);
            histogram = zeros(256, 1);
            [height, width] = size(image);

            for x = 1:width
                for y = 1:height
                    pixelValue = image(y, x) + 1;
                    histogram(pixelValue) = histogram(pixelValue) + 1;
                end
            end
            % 调用绘制直方图的方法
            draw_histogram(app, histogram);
        end

        
        function draw_histogram(app, histogram)
            % 获取灰度直方图坐标轴组件
            axesHandle = app.GrayHistgramUIAxes;

            % 绘制直方图
            bar(axesHandle, histogram);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: InputImage
        function InputImageOnClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);
            app.InputImage.ImageSource = imgData;
        end

        % Button pushed function: ShowGrayHistgram
        function ShowGrayHistgramOnClicked(app, event)

            if ~isempty(app.InputImage.ImageSource)
                % Compute the gray histogram
                histgram = gray_histogram(app, app.InputImage.ImageSource);


            else
                % Handle the case where the input image is empty
                disp('Input image is empty.');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '直方图处理';
            app.GrayHistgramUIAxes = uiaxes(app.Tab);
            title(app.GrayHistgramUIAxes, '灰度直方图')
            xlabel(app.GrayHistgramUIAxes, '灰度值')
            ylabel(app.GrayHistgramUIAxes, '频次')
            zlabel(app.GrayHistgramUIAxes, 'Z')
            app.ShowGrayHistgram = uibutton(app.Tab, 'push');
            app.ShowGrayHistgram.ButtonPushedFcn = createCallbackFcn(app, @ShowGrayHistgramOnClicked, true);
            app.ShowGrayHistgram.Text = '显示灰度直方图';
            app.GrayHistgram = uiimage(app.Tab);
            app.InputImage = uiimage(app.Tab);
            app.InputImage.ImageClickedFcn = createCallbackFcn(app, @InputImageOnClicked, true);
            app.Label = uilabel(app.Tab);
            app.Label.Text = '原始图像';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_view

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end